classdef SS3_Magnetic_Signature_app_2606_csv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneratesMagneticFieldSignalsUIFigure  matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SavecsvMenu                  matlab.ui.container.Menu
        ImportMenu                   matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        ConfigurationMenu            matlab.ui.container.Menu
        VelocityunitMenu             matlab.ui.container.Menu
        KnotMenu                     matlab.ui.container.Menu
        msMenu                       matlab.ui.container.Menu
        NumberofpointMenu            matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutMenu                    matlab.ui.container.Menu
        ManualMenu                   matlab.ui.container.Menu
        Panel_6                      matlab.ui.container.Panel
        Version1223Label             matlab.ui.control.Label
        MAGNETICPATTERNPanel         matlab.ui.container.Panel
        AboutPanel                   matlab.ui.container.Panel
        CopyrightBartingtonInstrumentsLabel  matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        Panel_2                      matlab.ui.container.Panel
        DefaultButton                matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        QuitButton                   matlab.ui.control.Button
        GenerateWaveformButton       matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        Panel_4                      matlab.ui.container.Panel
        DistanceMagneticmmEditField  matlab.ui.control.NumericEditField
        DistanceMagneticmmEditFieldLabel  matlab.ui.control.Label
        CPAmagneticfieldTEditField   matlab.ui.control.NumericEditField
        CPAmagneticfieldTEditFieldLabel  matlab.ui.control.Label
        LinearVelocitymmsEditField   matlab.ui.control.NumericEditField
        LinearVelocitymmsEditFieldLabel  matlab.ui.control.Label
        SimulateddistancemEditField  matlab.ui.control.NumericEditField
        SimulateddistancemEditField_2Label  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        VelocityEditField            matlab.ui.control.NumericEditField
        VelocityEditFieldLabel       matlab.ui.control.Label
        PointsEditField              matlab.ui.control.NumericEditField
        PointsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField           matlab.ui.control.NumericEditField
        DurationsEditField_2Label    matlab.ui.control.Label
        SimulatedtypeDropDown        matlab.ui.control.DropDown
        SimulatedtypeDropDownLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        warning
        warning1
        notification
        erroropenfile
        save
        filename
        data_type
        data_duration
        data_uint32_numberofpoint
        data_int16_velocity
        velocity
        data_int16_velocity_knot
        data_int16_simulated_distance
        data_uint16_linearvelocity
        data_int16_Actualdistance
        CPA_magnetic_field
        distance_magnetic_1
        distance_magnetic
        data_value
        status_import = 0;
        data_unit=0;
        csvFileName
        csvFullPath
        b
        savefilecsvname
        savefilename
        defaultFolder
        %status_import
        Property21 % Description
        status_open = 0
        status_error
        errorsavefile
        errorreadfile
        data_value_write
        status_round
        duration_bu
        m = 0.1;
    end
    
    
    methods (Access = public)
        
        function importfilename(app,value)
            filename=value;
            disp(filename);
            app.filename = filename;
            data_type = app.data_type;
            data_duration = app.data_duration;
            data_duration = round(data_duration * 50);
            data_uint32_numberofpoint = app.data_uint32_numberofpoint;
            data_int16_velocity = app.data_int16_velocity;
            data_uint16_linearvelocity = app.data_uint16_linearvelocity;
            data_int16_simulated_distance = app.data_int16_simulated_distance;
            data_int16_simulated_distance = round(data_int16_simulated_distance * 300);
            data_int16_Actualdistance = app.data_int16_Actualdistance;
            data_int16_Actualdistance =  round(double(data_int16_Actualdistance) * 65.5);
            CPA_magnetic_field = app.CPA_magnetic_field;
            data_value_write = app.data_value_write;
            defaultFolder=app.defaultFolder;
            
            % Get the file name from user input
            fileName = [filename, '.bar'];
            
            % Full path to the file
            full_path = fullfile(defaultFolder, fileName);

            % Create .bar file
            fid = fopen(full_path, 'wb'); % 'wb' write binary
            
            if fid == -1
                app.errorsavefile=errorsavefile(app,fileName);
                return;
            end
            % This script writes multiple variables with different data types to a .bar file
            fwrite(fid, 'bar ', 'char');
            fwrite(fid, data_type, 'int16');
            fwrite(fid, data_duration, 'int16');
            fwrite(fid, data_uint32_numberofpoint, 'uint32');
            fwrite(fid, data_int16_velocity, 'int16');
            fwrite(fid, data_uint16_linearvelocity, 'uint16');
            fwrite(fid, data_int16_simulated_distance, 'int16');
            fwrite(fid, data_int16_Actualdistance, 'int16');
            fwrite(fid, CPA_magnetic_field, 'double');
            fwrite(fid, int8(1:40), 'int8');
            fwrite(fid, data_value_write, 'int16');
            fclose(fid);
            app.notification=notification(app,fileName,full_path);
        end


        function importfilecsvname(app,value1)
            % Update file name
            filename = value1;
            app.filename = filename;
            
            % Get the CSV file name from user input
            csvFileName = [filename, '.csv'];
            defaultFolder = app.defaultFolder;
            
            % Full path to the CSV file
            csvFullPath = fullfile(defaultFolder, csvFileName);
            
            % Create CSV file and write data
            try
                csvFile = fopen(csvFullPath, 'w');
                if csvFile == -1
                    app.errorsavefile = errorsavefile(app, csvFileName);
                    return;
                end
                
                % Write the header
                fprintf(csvFile, 'Points,Magnetic strength (From 0 to 0.1T)\n');
                
                % Write the data
                disp('Length of data_value:');
                disp(length(app.b));
                
                for i = 1:length(app.b)
                    fprintf(csvFile, '%d,%.6f\n', i, app.b(i));
                    disp(sprintf('DataValue: %.6f', app.b(i)));
                end
                
                fclose(csvFile);
                
                % Notify user of success
                app.notification = notification(app, csvFileName, csvFullPath);
                
                % Save to app properties if needed
                app.csvFileName = csvFileName;
                app.csvFullPath = csvFullPath;
            catch ME
                % Close the file if it is open
                if exist('csvFile', 'var') && csvFile ~= -1
                    fclose(csvFile);
                end
                
                % Display error message
                app.errorsavefile = errorsavefile(app, csvFileName);
            end

        end
        
        
        function appwarning(app,status_warning,status_import)
            %Update warning and import status
            status_warning = status_warning;
            status_import = status_import;
            if status_warning == 0
                if status_import == 0
                    app.DurationsEditField.Value = 0;
                    app.PointsEditField.Value = 1;
                    app.GenerateWaveformButton.Enable = 'off';
                    return
                elseif status_import == 1
                    app.DurationsEditField.Value = 0;
                    app.GenerateWaveformButton.Enable = 'off';
                    return
                end
           
            end
        end
        
        function appwarning1(app,status_warning)
            %Update warning status
            status_warning = status_warning;
            disp('status warning');
            disp(status_warning);
            if status_warning == 1
                    return
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DurationsEditField.Enable = 'off';
            app.DistanceMagneticmmEditField.Enable = 'off';
            app.PointsEditField.Enable = 'off';
            app.SimulateddistancemEditField.Enable = 'off';
            app.VelocityEditField.Enable = 'off';
            app.LinearVelocitymmsEditField.Enable = 'off';
            app.CPAmagneticfieldTEditField.Enable = 'off';
            app.Label.Text = '(Knot)';
            app.SaveMenu.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.SavecsvMenu.Enable = 'off';
            app.GenerateWaveformButton.Enable = 'off';
            app.NumberofpointMenu.Enable = 'off';
            app.SaveButton.Enable = 'off';
            currentFilePath = mfilename('fullpath');
            currentFolder = fileparts(currentFilePath);
            app.KnotMenu.Enable = 'on';
            app.msMenu.Enable = 'on';
            data_unit=0;
            app.data_unit = data_unit;
            status_import = 0;
            app.status_import = status_import;
        end

        % Value changed function: SimulatedtypeDropDown
        function SimulatedtypeDropDownValueChanged(app, event)
            NaN = 0;
            value = app.SimulatedtypeDropDown.Value;
            switch value
                case '...'
                    app.KnotMenu.Enable = 'on';
                    app.msMenu.Enable = 'on';
                    app.DurationsEditField.Enable = 'off';
                    app.DistanceMagneticmmEditField.Enable = 'off';
                    app.PointsEditField.Enable = 'off';
                    app.SimulateddistancemEditField.Enable = 'off';
                    app.VelocityEditField.Enable = 'off';
                    app.LinearVelocitymmsEditField.Enable = 'off';
                    app.GenerateWaveformButton.Enable = 'off';
                    app.CPAmagneticfieldTEditField.Enable ='off';
                    app.DurationsEditField.Value = 0;
                    app.PointsEditField.Value = 1;
                    app.VelocityEditField.Value = NaN;
                    app.DistanceMagneticmmEditField.Value = 1;
                    app.LinearVelocitymmsEditField.Value = NaN;
                    app.CPAmagneticfieldTEditField.Value = NaN; 
                    cla(app.UIAxes);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    app.SimulateddistancemEditField.Value = 1;
                    status_open = 0;
                    app.status_open = status_open;
                    app.DurationsEditField.Editable = true;
                    app.DistanceMagneticmmEditField.Editable = true;
                    app.CPAmagneticfieldTEditField.Editable = true;
                    app.LinearVelocitymmsEditField.Editable = true;
                    app.SimulateddistancemEditField.Editable = true;
                    app.VelocityEditField.Editable = true;
                    app.PointsEditField.Editable = true;
                case 'Magnetic Variation'
                    app.KnotMenu.Enable = 'on';
                    app.msMenu.Enable = 'on';
                    app.DurationsEditField.Enable = 'on';
                    app.DistanceMagneticmmEditField.Enable = 'on';
                    app.PointsEditField.Enable = 'on';
                    app.SimulateddistancemEditField.Enable = 'on';
                    app.VelocityEditField.Enable = 'on';
                    app.GenerateWaveformButton.Enable = 'off';
                    app.CPAmagneticfieldTEditField.Enable = 'on';
                    app.LinearVelocitymmsEditField.Enable = 'on';
                    data_type = 1;
                    app.data_type = data_type;
                    disp(data_type);
                    app.DurationsEditField.Value = 0;
                    app.PointsEditField.Value = 1;
                    app.VelocityEditField.Value = NaN;
                    app.PointsEditField.Value = 1;
                    app.SimulateddistancemEditField.Value = 1;
                    app.DistanceMagneticmmEditField.Value = 1;
                    app.LinearVelocitymmsEditField.Value = NaN;
                    app.CPAmagneticfieldTEditField.Value = NaN;
                    cla(app.UIAxes);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    status_open = 0;
                    app.status_open = status_open;
                    app.DurationsEditField.Editable = true;
                    app.DistanceMagneticmmEditField.Editable = true;
                    app.CPAmagneticfieldTEditField.Editable = true;
                    app.LinearVelocitymmsEditField.Editable = true;
                    app.SimulateddistancemEditField.Editable = true;
                    app.VelocityEditField.Editable = true;
                    app.PointsEditField.Editable = true;
                case 'Magnetic Movement'
                    app.KnotMenu.Enable = 'on';
                    app.msMenu.Enable = 'on';
                    app.DurationsEditField.Enable = 'on';
                    app.DistanceMagneticmmEditField.Enable = 'on';
                    app.PointsEditField.Enable = 'on';
                    app.SimulateddistancemEditField.Enable = 'on';
                    app.VelocityEditField.Enable = 'on';
                    app.GenerateWaveformButton.Enable = 'off';
                    app.LinearVelocitymmsEditField.Enable = 'off';
                    app.CPAmagneticfieldTEditField.Enable = 'on';
                    data_type = 2;
                    app.data_type = data_type;
                    disp(data_type);
                    app.DurationsEditField.Value = 0;
                    app.PointsEditField.Value = 1;
                    app.VelocityEditField.Value = NaN;
                    app.PointsEditField.Value = 1;
                    app.DistanceMagneticmmEditField.Value = 1;
                    app.LinearVelocitymmsEditField.Value = NaN;
                    app.SimulateddistancemEditField.Value = 1;
                    app.CPAmagneticfieldTEditField.Value = NaN;
                    cla(app.UIAxes);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    status_open = 0;
                    app.status_open = status_open;
                    app.DurationsEditField.Editable = true;
                    app.DistanceMagneticmmEditField.Editable = true;
                    app.CPAmagneticfieldTEditField.Editable = true;
                    app.LinearVelocitymmsEditField.Editable = true;
                    app.SimulateddistancemEditField.Editable = true;
                    app.VelocityEditField.Editable = true;
                    app.PointsEditField.Editable = true;
            end
        end

        % Value changed function: DurationsEditField
        function DurationsEditFieldValueChanged(app, event)
            status_open = app.status_open;
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            else
                data_duration = app.DurationsEditField.Value;
                data_duration = round(data_duration,1);
                app.DurationsEditField.Value = data_duration;
                data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
                data_int16_velocity = app.VelocityEditField.Value;
                data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
                data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
                data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
                CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
                status_import = app.status_import;
                if data_uint32_numberofpoint ~= 0
                    if data_duration < double(0.08*data_uint32_numberofpoint)
                        app.warning=Warning(app,status_import)  
                    end
                end
                value = app.SimulatedtypeDropDown.Value;
                switch value
                    case '...'
                        
                    case 'Magnetic Variation'
                        status_import = app.status_import;
                        disp(status_import);
                        if data_int16_velocity == 0 && status_import == 0
                            app.SimulateddistancemEditField.Enable = 'on';
                            app.DistanceMagneticmmEditField.Enable = 'on';
                            if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                                app.GenerateWaveformButton.Enable = 'on';
                            else
                                app.GenerateWaveformButton.Enable = 'off';
                            end
                        else
                            app.SimulateddistancemEditField.Enable = 'on';
                            app.DistanceMagneticmmEditField.Enable = 'on';
                            if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                                app.GenerateWaveformButton.Enable = 'on';
                            else
                                app.GenerateWaveformButton.Enable = 'off';
                            end
                        end
                    case 'Magnetic Movement'
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  && data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300  && CPA_magnetic_field ~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                end
            end
        end

        % Value changed function: PointsEditField
        function PointsEditFieldValueChanged(app, event)
            status_open = app.status_open;
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            else
                status_import = 0;
                app.status_import = status_import;
                data_duration = app.DurationsEditField.Value;
                data_uint32_numberofpoint = app.PointsEditField.Value;
                app.PointsEditField.Value = round(data_uint32_numberofpoint);
                data_int16_velocity = app.VelocityEditField.Value;
                data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
                data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
                data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
                CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
                status_import = app.status_import;
                if data_duration ~= 0
                    if data_uint32_numberofpoint > double(12.5*data_duration)
                        app.warning=Warning(app,status_import);
                    end
                end
                value = app.SimulatedtypeDropDown.Value;
                switch value
                    case '...'
                        
                    case 'Magnetic Variation'
                        status_import = app.status_import;
                        disp(status_import);
                        if data_int16_velocity == 0 && status_import == 0
                            app.SimulateddistancemEditField.Enable = 'on';
                            app.DistanceMagneticmmEditField.Enable = 'on';
                            if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                                app.GenerateWaveformButton.Enable = 'on';
                            else
                                app.GenerateWaveformButton.Enable = 'off';
                            end
                        else
                            app.SimulateddistancemEditField.Enable = 'on';
                            app.DistanceMagneticmmEditField.Enable = 'on';
                            if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300&& data_uint16_linearvelocity~=0
                                app.GenerateWaveformButton.Enable = 'on';
                            else
                                app.GenerateWaveformButton.Enable = 'off';
                            end
                        end
                    case 'Magnetic Movement'
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30  && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && CPA_magnetic_field ~=0 
                            app.GenerateWaveformButton.Enable = 'on';
                            
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                    end
                end
            end
        end

        % Button pushed function: GenerateWaveformButton
        function GenerateWaveformButtonPushed(app, event)
            m = app.m;
            app.SaveMenu.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.SavecsvMenu.Enable = 'on';
            data_type = app.data_type;
            data_duration = app.DurationsEditField.Value;
            data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
            data_int16_velocity_knot = int16(app.VelocityEditField.Value);
            data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
            data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
            data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
            CPA_magnetic_field = app.CPA_magnetic_field;
            CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
            data_unit = app.data_unit;
                if data_unit ==0
                    data_int16_velocity = double(app.VelocityEditField.Value) * double(0.5144444444);
                    
                elseif data_unit == 1
                    data_int16_velocity = double(app.VelocityEditField.Value);
                end
            status_import = app.status_import;
            if status_import ==0
                value = app.SimulatedtypeDropDown.Value;
                switch value
                    case '...'
                        
                    case 'Magnetic Variation'
                        total_point =(data_duration./m);
                        % Create and display the progress dialog
                        %fig = uifigure;
                        d = uiprogressdlg(app.GeneratesMagneticFieldSignalsUIFigure, 'Title', 'Please Wait', 'Message', 'Opening the application');
                        %pause(0.5)
                        d.Value = 0.1;
                        d.Message = 'Calculating data value...';
                        data_uint16_linearvelocity= double(double(data_uint16_linearvelocity)*65535/1000);% mm -> m
                        if data_int16_velocity == 0
                            app.SavecsvMenu.Enable = 'off';
                            x = double(m * double(1:round(data_duration./m)));
                            y = ones(size(x));
                            b = y * CPA_magnetic_field;
                            y = b*1000000;
                            disp(y);
                            if CPA_magnetic_field >0
                                y_max = CPA_magnetic_field;
                                y_min = - CPA_magnetic_field;
                            else
                                y_max = - CPA_magnetic_field;
                                y_min = CPA_magnetic_field;
                            end
                            y_max = 2*y_max*1000000;
                            y_min =2*y_min*1000000;
                            %app.LinearVelocitymmsEditField.Value = data_uint16_linearvelocity_2;
                            plot(app.UIAxes, x, y);
                            disableDefaultInteractivity(app.UIAxes);
                            zoom(app.UIAxes, 'off');
                            ylim(app.UIAxes, [y_min, y_max]);
                            xlim(app.UIAxes, [min(x),max(x)]);
                            app.b = b;
                            data_value = double(b) ;
                            data_value_write = double(b) * 32767;
                        else
                            %Calculate velocity (m/s)
                            % Calculate distance
                            distance_magnetic_1 = double(double(data_duration) * double(data_int16_velocity));
                            disp(sprintf('%.6f', distance_magnetic_1));
                            %
                            data_uint32_numberofpoint = data_duration*10;
                            % Calculate distance between points
                            distance_magnetic = double(distance_magnetic_1) / double(data_uint32_numberofpoint - 1);
                            disp(sprintf('%.6f', distance_magnetic));
                            % Calculate half array
                            if rem(data_uint32_numberofpoint, 2) == 0
                                data_uint32_numberofpoint_1=floor(data_uint32_numberofpoint./2);
                                disp(data_uint32_numberofpoint_1);
                            else 
                                data_uint32_numberofpoint_1=floor(data_uint32_numberofpoint./2);
                                disp(data_uint32_numberofpoint_1);
                            end
                            d.Value = 0.2;  % Adjust progress based on calculation complexity
                            d.Message = 'Calculating data value...';
                            % Tính toán mảng a 
                            
                            a_real = double((double(distance_magnetic_1)) / 2 - double(distance_magnetic) * double(0:data_uint32_numberofpoint_1 - 1));
                            disp(a_real);     
                            DistancetoDUT_double = double(data_int16_simulated_distance);
                            Sn =  double(DistancetoDUT_double^2 + a_real.^2);
                            disp(Sn);
                            d_array = double(CPA_magnetic_field)*1 ./ (DistancetoDUT_double^2 + a_real.^2);
                            % display array d
                            for i = 1:length(d_array)
                                disp('d_array');
                                disp(sprintf('%.7f', d_array(i)));
                            end
                            d.Value = 0.5;  % Adjust progress based on calculation complexity
                            d.Message = 'Calculating data value...';
                            
                            % calculate array b
                            if rem(data_uint32_numberofpoint, 2) == 0
                                num_elements = length(d_array) * 2;
                                b = zeros(1, num_elements);
                                b(1:length(d_array)) = d_array;
                            
                                % Create a symmetrical array of the first half of array b
                                b_rev = b(1:end-1);
                                if length(b_rev) < length(b)/2  
                                    b_rev(end+1) = 0;
                                end
                                b_rev = b_rev(end:-1:1);
                            
                                % Copy the symmetric array into the second half of array b
                                b_rev = b(1:length(d_array));
                                b_rev = b_rev(end:-1:1);
                            
                                % Copy b_rev to the second half of b (may have trailing zero)
                                b(length(d_array)+1:end) = b_rev;
                                
                                if CPA_magnetic_field > 0
                                    max_value = max(b);
                                else
                                    max_value = min(b);
                                end
                                b = b./double(max_value);
                                b = b * double(CPA_magnetic_field);
                                disp(max_value);
                                disp('b');
                                %
                                d.Value = 0.7;  
                                d.Message = 'Calculating data value...';
                                %----------------------
                                data_value_array = 32767* double(CPA_magnetic_field)* double(d_array)./double(max_value);
                                data_value = zeros(1, num_elements);
                                data_value(1:length(data_value_array)) = data_value_array;
                            
                                % Create a symmetrical array of the first half of array b
                                data_value_rev = data_value(1:end-1);
                                if length(data_value_rev) < length(data_value)/2  
                                    data_value_rev(end+1) = 0;
                                end
                                data_value_rev = data_value_rev(end:-1:1);
                            
                                % Copy the symmetric array into the second half of array b
                                data_value_rev = data_value(1:length(data_value_array));
                                data_value_rev = data_value_rev(end:-1:1);
                            
                                % Copy b_rev to the second half of b (may have trailing zero)
                                data_value(length(data_value_array)+1:end) = data_value_rev;
                                format longG; 
                             else
                                num_elements = (length(d_array) * 2) - 1;
                                b = zeros(1, num_elements);
                                b(1:length(d_array)) = d_array;
                            
                                % Create a symmetrical array of the first half of array b
                                b_rev = b(1:length(d_array));
                                b_rev = b_rev(end:-1:1);
                            
                                % Copy the symmetric array into the second half of array b
                                b(length(d_array):end) = b_rev; 
                            
                                if CPA_magnetic_field > 0
                                    max_value = max(b);
                                else
                                    max_value = min(b);
                                end
                                b = b./double(max_value);
                                b = b * double(CPA_magnetic_field);
                                %b = b * double(CPA_magnetic_field);
                                disp(b);
                                d.Value = 0.7;  % Adjust progress based on calculation complexity
                                d.Message = 'Calculating data value...';
                                %-----------------------
                                data_value_array = 32767* double(CPA_magnetic_field)* double(d_array)./double(max_value);
                                data_value = zeros(1, num_elements);
                                data_value(1:length(data_value_array)) = data_value_array;
                            
                                % Create a symmetrical array of the first half of array b
                                data_value_rev = data_value(1:length(data_value_array));
                                data_value_rev = data_value_rev(end:-1:1);
                            
                                % Copy the symmetric array into the second half of array b
                                data_value(length(data_value_array):end) = data_value_rev; 
                                format longG;
                            end
                            % Plot results
                            app.b = b;
                            % create array x
                            x = double(m * double(1:round(data_duration./m)));
                            y = b*1000000;
                            if CPA_magnetic_field >0
                                y_max = CPA_magnetic_field*1000000;
                                y_min = - CPA_magnetic_field*1000000;
                            else
                                y_max = - CPA_magnetic_field*1000000;
                                y_min = CPA_magnetic_field*1000000;
                            end
                            plot(app.UIAxes, x, y);
                            data_value_write = b*32767;
                            disableDefaultInteractivity(app.UIAxes);
                            zoom(app.UIAxes, 'off');
                            ylim(app.UIAxes, [y_min, y_max]);
                            xlim(app.UIAxes, [min(x),max(x)]);
                            d.Value = 1;
                            d.Message = 'Finishing';
                            pause(1)
                            close(d);
                        end
                    case 'Magnetic Movement'
                        w = double(double(double(data_duration) * double(data_int16_velocity))*double(data_int16_Actualdistance));
                        w = double(double(w) / double(data_int16_simulated_distance));
                        d = uiprogressdlg(app.GeneratesMagneticFieldSignalsUIFigure, 'Title', 'Please Wait', 'Message', 'Opening the application');
                        d.Value = 0.1;  % Adjust progress based on calculation complexity
                        d.Message = 'Calculating data value...';
                        d.Value = 0.3;  % Adjust progress based on calculation complexity
                        d.Message = 'Calculating data value...';
                        data_uint16_linearvelocity_1 =double(double(data_int16_velocity) * double(data_int16_Actualdistance));
                        data_uint16_linearvelocity_2 = double(double(data_uint16_linearvelocity_1)/double(data_int16_simulated_distance)) ;
                        disp(sprintf('%.6f', data_uint16_linearvelocity_2));
                        d.Value = 0.6;  % Adjust progress based on calculation complexity
                        d.Message = 'Calculating data value...';
                        if data_uint16_linearvelocity_2 <= 1000 %Limit linearvelocity (0 1000)
                            data_uint16_linearvelocity= double(double(data_uint16_linearvelocity_2)*65535/1000);% mm -> m
                            s = double(double(data_uint16_linearvelocity_2)*double(data_duration));
                        else
                            data_uint16_linearvelocity_2 = 1000;
                            data_uint16_linearvelocity= 65535;
                            s = double(1000*double(data_duration));
                        end 
                        if w < 1450 
                            %25/06--------------------------
                            duration_bu = double(double(725-double(w/2))/data_uint16_linearvelocity_2);
                            m = app.m;
                            point_bu = round(duration_bu/m);
                            array_bu = zeros(1, point_bu);
                            total_point = point_bu + (data_duration./m);
                            %-------------------------------
                        else
                            total_point =(data_duration./m);
                            app.warning1=Warning1(app)
                        end
                        
                        % create array time_point
                        x = double(m * double(1:round(data_duration./m)));
                        y = ones(size(x));
                        b = y * CPA_magnetic_field;
                        y = b*1000000;
                        if CPA_magnetic_field >0
                            y_max = CPA_magnetic_field;
                            y_min = - CPA_magnetic_field;
                        else
                            y_max = - CPA_magnetic_field;
                            y_min = CPA_magnetic_field;
                        end
                        app.LinearVelocitymmsEditField.Enable = 'on';
                        app.LinearVelocitymmsEditField.Editable = false;
                        app.LinearVelocitymmsEditField.Value = round(data_uint16_linearvelocity_2,1);
                        plot(app.UIAxes, x, y);
                        disableDefaultInteractivity(app.UIAxes);
                        zoom(app.UIAxes, 'off');
                        y_max = 2*y_max*1000000;
                        y_min =2*y_min*1000000;
                        ylim(app.UIAxes, [y_min, y_max]);
                        xlim(app.UIAxes, [min(x),max(x)]);
                        app.b = b;
                        data_value = double(b) ;
                        if w < 1450 
                            data_value_dabu=[array_bu, data_value];
                            disp(data_value_dabu);
                            data_value_write = double(b) * 32767;
                            data_value_write=[array_bu, data_value_write];
                        else
                            data_value_write = double(b) * 32767;
                        end
                        d.Value = 1;
                        d.Message = 'Finishing';
                        pause(1)
                        close(d);
                
                end  
            elseif status_import == 1
                % Display a progress dialog with a title and message while the application is opening 
                d = uiprogressdlg(app.GeneratesMagneticFieldSignalsUIFigure, 'Title', 'Please Wait', 'Message', 'Opening the application');
                d.Value = 0.1;
                d.Message = 'Calculating data value...';
                value = app.SimulatedtypeDropDown.Value;
                data_value = app.data_value;
            switch value
                case '...'
                    
                case 'Magnetic Variation'
                    data_uint16_linearvelocity= double(double(data_uint16_linearvelocity)*65535/1000);% mm -> m c
                    CPA_magnetic_field = app.CPA_magnetic_field;
                    data_uint32_numberofpoint = app.data_uint32_numberofpoint;
                    data_value = app.data_value;
                    x = double(m * double(1:round(data_duration./m)));
                    plot(app.UIAxes, x, data_value*1000000);
                    disableDefaultInteractivity(app.UIAxes);
                    zoom(app.UIAxes, 'off');
                    if CPA_magnetic_field >0
                        y_max = CPA_magnetic_field*1000000;
                        y_min = - CPA_magnetic_field*1000000;
                    else
                        y_max = - CPA_magnetic_field*1000000;
                        y_min = CPA_magnetic_field*1000000;
                    end
                    if min(data_value) ~= max(data_value)
                        ylim(app.UIAxes, [y_min, y_max]);
                    else
                        y_max = 2*y_max;
                        y_min =2*y_min;
                        ylim(app.UIAxes, [y_min, y_max]);
                    end
                    xlim(app.UIAxes, [min(x),max(x)]);
                    xticklabels(app.UIAxes, 'auto');
                    xlabel(app.UIAxes, 'Time(s)');
                    data_value_write = data_value *32767;
                    d.Value = 1;
                    d.Message = 'Finishing';
                    pause(1)
                    close(d);
                    total_point = data_uint32_numberofpoint
                case 'Magnetic Movement'
                    w = double(double(double(data_duration) * double(data_int16_velocity))*double(data_int16_Actualdistance));
                    w = double(double(w) / double(data_int16_simulated_distance));
                    d.Value = 0.1;  % Adjust progress based on calculation complexity
                    d.Message = 'Calculating data value...';
                    d.Value = 0.3;  % Adjust progress based on calculation complexity
                    d.Message = 'Calculating data value...';
                    data_uint16_linearvelocity_1 =double(double(data_int16_velocity) * double(data_int16_Actualdistance));
                    disp(data_uint16_linearvelocity_1);
                    data_uint16_linearvelocity_2 = double(double(data_uint16_linearvelocity_1)/double(data_int16_simulated_distance)) ;
                    disp('Linear Velocity (mm/s):');
                    disp(sprintf('%.6f', data_uint16_linearvelocity_2));
                    d.Value = 0.6;  % Adjust progress based on calculation complexity
                    d.Message = 'Calculating data value...';
                        
                    if data_uint16_linearvelocity_2 <= 1000 %Limit linearvelocity (0 1000)
                        data_uint16_linearvelocity= double(double(data_uint16_linearvelocity_2)*65535/1000);% mm -> m
                        s = double(double(data_uint16_linearvelocity_2)*double(data_duration));
                    else
                        data_uint16_linearvelocity_2 = 1000;
                        data_uint16_linearvelocity= 65535;
                        s = double(1000*double(data_duration));
                    end 
                        app.LinearVelocitymmsEditField.Enable = 'on';
                        app.LinearVelocitymmsEditField.Editable = false;
                        app.LinearVelocitymmsEditField.Value = round(data_uint16_linearvelocity_2,1);
                    if w < 1450 
                        %25/06--------------------------
                        duration_bu = double(double(725-double(w/2))/data_uint16_linearvelocity_2);
                        m = app.m;
                        point_bu = round(duration_bu/m);
                        array_bu = zeros(1, point_bu);
                        total_point = point_bu + (data_duration./m);
                        %-------------------------------
                    else
                        total_point =(data_duration./m);
                        app.warning1=Warning1(app)
                    end
                    % create array time_point
                    x = double(m * double(1:round(data_duration./m)));
                    y = ones(size(x));
                    b = y * CPA_magnetic_field;
                    y = b*1000000;
                    if CPA_magnetic_field >0
                        y_max = CPA_magnetic_field;
                        y_min = - CPA_magnetic_field;
                    else
                        y_max = - CPA_magnetic_field;
                        y_min = CPA_magnetic_field;
                    end
                    
                    app.LinearVelocitymmsEditField.Enable = 'on';
                    app.LinearVelocitymmsEditField.Editable = false;
                    app.LinearVelocitymmsEditField.Value = round(data_uint16_linearvelocity_2,1);
                    plot(app.UIAxes, x, y);
                    disableDefaultInteractivity(app.UIAxes);
                    zoom(app.UIAxes, 'off');
                    y_max = 2*y_max*1000000;
                    y_min =2*y_min*1000000;
                    ylim(app.UIAxes, [y_min, y_max]);
                    xlim(app.UIAxes, [min(x),max(x)]);
                    xticklabels(app.UIAxes, 'auto');
                    xlabel(app.UIAxes, 'Time(s)');
                    app.b = b;
                    data_value = double(b) ;
                    if w < 1450 
                        data_value_dabu=[array_bu, data_value];
                        disp(data_value_dabu);
                        data_value_write = double(b) * 32767;
                        data_value_write=[array_bu, data_value_write];
                    else
                        data_value_write = double(b) * 32767;
                    end
                    d.Value = 1;
                    d.Message = 'Finishing';
                    pause(1)
                    close(d);   
                end
                
        end        
            data_int16_velocity = double(32767*double(double(data_int16_velocity)/double(15.433333)));
            app.data_type = data_type;
            app.data_duration = data_duration;
            app.data_uint32_numberofpoint = total_point;
            app.data_int16_velocity = data_int16_velocity;
            app.data_uint16_linearvelocity = data_uint16_linearvelocity;
            app.data_int16_simulated_distance = data_int16_simulated_distance;
            app.data_int16_Actualdistance = data_int16_Actualdistance;
            app.CPA_magnetic_field = CPA_magnetic_field;
            app.data_value = data_value;
            app.data_value_write = data_value_write;
            app.data_unit = data_unit; 
            app.SaveButton.Enable = 'on';
        end

        % Menu selected function: KnotMenu
        function KnotMenuSelected(app, event)
            app.Label.Text = '(Knot)';
            app.VelocityEditField.Limits = [0 30];
            data_unit = app.data_unit;
            if data_unit == 1
                data_int16_velocity = 0;
                app.VelocityEditField.Value = data_int16_velocity;
            end
            data_unit=0;
            app.data_unit = data_unit;
        end

        % Menu selected function: msMenu
        function msMenuSelected(app, event)
            app.Label.Text = '(m/s)';
            data_unit = app.data_unit;
            if data_unit == 0
                data_int16_velocity = 0;
                app.VelocityEditField.Value = data_int16_velocity;
            end
            app.VelocityEditField.Limits = [0 15.433333];
            data_unit=1;
            app.data_unit = data_unit;
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            status_open = app.status_open;
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            else
            data_duration = app.DurationsEditField.Value;
            data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
            data_int16_velocity = app.VelocityEditField.Value;
            app.VelocityEditField.Value = round(data_int16_velocity,2);
            data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
            data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
            data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
            CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
            data_unit = app.data_unit;
            if data_unit == 0
                data_int16_velocity = data_int16_velocity;
            elseif data_unit ==1
                data_int16_velocity = double(data_int16_velocity ./ 0.5144444444);
            end
            value = app.SimulatedtypeDropDown.Value;
            switch value
                case '...'
                    
                case 'Magnetic Variation'
                    status_import = app.status_import;
                    disp(status_import);
                    if data_int16_velocity == 0 && status_import == 0
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    else
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    end
                case 'Magnetic Movement'
                    if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && CPA_magnetic_field ~=0 
                        app.GenerateWaveformButton.Enable = 'on';
                    else
                        app.GenerateWaveformButton.Enable = 'off';
                    end
                end
            
            end
        end

        % Value changed function: SimulateddistancemEditField
        function SimulateddistancemEditFieldValueChanged(app, event)
            status_open = app.status_open;
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            else
            data_duration = app.DurationsEditField.Value;
            data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
            data_int16_velocity = app.VelocityEditField.Value;
            data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
            data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
            app.SimulateddistancemEditField.Value = round(data_int16_simulated_distance,2);
            data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
            CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
            disp(CPA_magnetic_field);
            value = app.SimulatedtypeDropDown.Value;
            switch value
                case '...'
                    
                case 'Magnetic Variation'
                    status_import = app.status_import;
                    disp(status_import);
                    if data_int16_velocity == 0 && status_import == 0
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    else
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    end
                case 'Magnetic Movement'
                    if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && CPA_magnetic_field ~=0 
                        app.GenerateWaveformButton.Enable = 'on';
                    else
                        app.GenerateWaveformButton.Enable = 'off';
                    end
            end
            end
        end

        % Value changed function: LinearVelocitymmsEditField
        function LinearVelocitymmsEditFieldValueChanged(app, event)
            status_open = app.status_open;
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            
            else
            data_duration = app.DurationsEditField.Value;
            data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
            data_int16_velocity = app.VelocityEditField.Value;
            data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
            app.LinearVelocitymmsEditField.Value = round(data_uint16_linearvelocity,1);
            data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
            data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
            CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
            value = app.SimulatedtypeDropDown.Value;
            switch value
                case '...'
                    
                case 'Magnetic Variation'
                    status_import = app.status_import;
                    disp(status_import);
                    if data_int16_velocity == 0 && status_import == 0
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    else
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    end
                case 'Magnetic Movement'
                    if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30  && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && CPA_magnetic_field ~=0 
                        app.GenerateWaveformButton.Enable = 'on';
                    else
                        app.GenerateWaveformButton.Enable = 'off';
                    end
                end
            end
        end

        % Value changed function: DistanceMagneticmmEditField
        function DistanceMagneticmmEditFieldValueChanged(app, event)
            status_open = app.status_open;
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            else
            data_duration = app.DurationsEditField.Value;
            data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
            data_int16_velocity = app.VelocityEditField.Value;
            data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
            data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
            data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
            app.DistanceMagneticmmEditField.Value = round(data_int16_Actualdistance,1);
            CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
            value = app.SimulatedtypeDropDown.Value;
            switch value
                case '...'
                    
                case 'Magnetic Variation'
                    status_import = app.status_import;
                    disp(status_import);
                    if data_int16_velocity == 0 && status_import == 0
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    else
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    end
                case 'Magnetic Movement'
                    if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30  && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && CPA_magnetic_field ~=0 
                        app.GenerateWaveformButton.Enable = 'on';
                    else
                        app.GenerateWaveformButton.Enable = 'off';
                    end
                end
            end
        end

        % Value changed function: CPAmagneticfieldTEditField
        function CPAmagneticfieldTEditFieldValueChanged(app, event)
            status_open = app.status_open
            if status_open == 1
                app.GenerateWaveformButton.Enable = 'off';
            
            else
            status_import = 0;
            app.status_import = status_import;
            %value = app.CPAmagneticfieldTEditField.Value;
            data_duration = app.DurationsEditField.Value;
            data_uint32_numberofpoint = uint32(app.PointsEditField.Value);
            data_int16_velocity = app.VelocityEditField.Value;
            data_uint16_linearvelocity = app.LinearVelocitymmsEditField.Value;
            data_int16_simulated_distance = app.SimulateddistancemEditField.Value;
            data_int16_Actualdistance = app.DistanceMagneticmmEditField.Value;
            CPA_magnetic_field = double(app.CPAmagneticfieldTEditField.Value);
            value = app.SimulatedtypeDropDown.Value;
            switch value
                case '...'
                    
                case 'Magnetic Variation'
                    status_import = app.status_import;
                    disp(status_import);
                    if data_int16_velocity == 0 && status_import == 0
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_duration > 0 && data_uint32_numberofpoint > 0 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    else
                        app.SimulateddistancemEditField.Enable = 'on';
                        app.DistanceMagneticmmEditField.Enable = 'on';
                        if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30 && CPA_magnetic_field ~=0 && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 && data_uint16_linearvelocity~=0
                            app.GenerateWaveformButton.Enable = 'on';
                        else
                            app.GenerateWaveformButton.Enable = 'off';
                        end
                    end
                case 'Magnetic Movement'
                    if data_int16_simulated_distance >=1 &&data_int16_simulated_distance <=100  &&data_duration > 0 && data_uint32_numberofpoint > 0 &&data_int16_velocity >0 &&data_int16_velocity <=30  && data_int16_Actualdistance >=1 && data_int16_Actualdistance <=300 &&CPA_magnetic_field ~=0 
                        app.GenerateWaveformButton.Enable = 'on';
                    else
                        app.GenerateWaveformButton.Enable = 'off';
                    end
                end
            end
        end

        % Callback function
        function EXITButtonPushed(app, event)
            delete(app.GeneratesMagneticFieldSignalsUIFigure);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app.GeneratesMagneticFieldSignalsUIFigure);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
           % Prompt user to select a file
           m = app.m;
            [fileName, filePath] = uigetfile('*.bar', 'Select the .bar file');
        
            % Check if user canceled the dialog
            if isequal(fileName, 0)
                disp('User canceled the file selection');
                status_open = 0;
                app.status_open = status_open;
                return; % Exit callback if user cancels
            end
            app.SaveMenu.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.SavecsvMenu.Enable = 'off';
            app.VelocityEditField.Limits = [0 15.433333];
            status_open = 1;
            app.status_open = status_open;
            % Create full path for the .BAR file
            fullFilePath = fullfile(filePath, fileName);
            % Open file for reading
            fid = fopen(fullFilePath, 'rb'); % 'rb' means read binary
            if fid == -1
                %error('Cannot open file for reading.');
                %message = sprintf('Cannot open file for reading.');
                app.erroropenfile=erroropenfile(app,fileName);
                app.SaveMenu.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.SavecsvMenu.Enable = 'off';
            end
            app.Label.Text = '(m/s)';
            app.data_unit=1;
            app.SaveMenu.Enable = 'on';
            app.SaveButton.Enable = 'on';
            app.SavecsvMenu.Enable = 'on';
            try
                % Read data from file
                data_char = fread(fid, 4, 'char=>char')'; % Read 4 characters
                
                data_type = fread(fid, 1, 'int16'); % Read 1 int16 value
                if isempty(data_type)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    disp('data_type');
                    return
                end
                data_duration = fread(fid, 1, 'int16'); % Read 1 int16 value
                if isempty(data_duration)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                else
                    data_duration = round((double(data_duration) / 50),1);
                end
                data_uint32_numberofpoint = fread(fid, 1, 'uint32'); % Read 1 uint32 value
                if isempty(data_uint32_numberofpoint)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                end
                data_int16_velocity_knot = fread(fid, 1, 'int16'); % Read 1 int16 value
                if isempty(data_int16_velocity_knot)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                end
                data_uint16_linearvelocity = fread(fid, 1, 'uint16'); % Read 1 uint16 value
                if isempty(data_uint16_linearvelocity)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                end
                data_int16_simulated_distance = fread(fid, 1, 'int16'); % Read 1 int16 value
                if isempty(data_int16_simulated_distance)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                else
                    data_int16_simulated_distance = round(double(data_int16_simulated_distance /300),2);
                end
                data_int16_Actualdistance = fread(fid, 1, 'int16'); % Read 1 int16 value
                if isempty(data_int16_Actualdistance)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                else
                    data_int16_Actualdistance= round(double(data_int16_Actualdistance /65.5),1);
                end
                CPA_magnetic_field = fread(fid, 1, 'double'); % Read 1 double value
                if isempty(CPA_magnetic_field)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                end
                %data_unit = fread(fid, 1, 'int16'); % Read 1 int16 value
                data_Reserved = fread(fid, 40, 'int8'); % Read 38 int8 values
                if length(data_Reserved) ~= 40
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                end
                data_value_convert = fread(fid, 'int16'); % Read remaining int16 values
                if isempty(data_value_convert)
                    app.errorreadfile=errorreadfile(app,fileName);
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                    app.SavecsvMenu.Enable = 'off';
                    return
                else
                    disp(data_value_convert);
                end
                app.SavecsvMenu.Enable = 'on';
                app.DurationsEditField.Enable = 'on';
                app.DurationsEditField.Editable = false;
                app.DistanceMagneticmmEditField.Enable = 'on';
                app.DistanceMagneticmmEditField.Editable = false;
                app.PointsEditField.Enable = 'on';
                app.PointsEditField.Editable = false;
                app.SimulateddistancemEditField.Enable = 'on';
                app.SimulateddistancemEditField.Editable = false;
                app.VelocityEditField.Enable = 'on';
                app.VelocityEditField.Editable = false;
                app.GenerateWaveformButton.Enable = 'off';
                app.LinearVelocitymmsEditField.Enable = 'on';
                app.LinearVelocitymmsEditField.Editable = false;
                app.CPAmagneticfieldTEditField.Enable = 'on';
                app.CPAmagneticfieldTEditField.Editable = false;
                app.SimulatedtypeDropDown.Editable = false;
                % Process data based on data_type value
                if data_type == 1
                    data_value = data_value_convert ./ 32767;
                    if CPA_magnetic_field > 0
                        max_data_value = max(data_value);
                        data_value = double(double(data_value/max_data_value)*CPA_magnetic_field);
                    else
                        min_data_value = min(data_value);
                        data_value = double(double(data_value/min_data_value)*CPA_magnetic_field);
                    end
                    data_int16_velocity = double(double(double(data_int16_velocity_knot) * double(15.433333))/32767);
                    data_int16_velocity = round(data_int16_velocity,2);
                    data_uint16_linearvelocity = double(data_uint16_linearvelocity) * 1000 / 65535;
                    data_uint16_linearvelocity = round(data_uint16_linearvelocity,1);
                    % Display data
                    disp(['Char: ' data_char]);
                    disp(['Control: ' num2str(data_type) ' - Change kepco']);
                    disp(['Duration: ' num2str(data_duration) 's']);
                    disp(['Number of Sample: ' num2str(data_uint32_numberofpoint) ' points']);
                    disp(['Velocity: ' num2str(data_int16_velocity) ' (m/s)']);
                    disp(['Linear Velocity: ' num2str(data_uint16_linearvelocity)]);
                    disp(['Distance to DUT: ' num2str(data_int16_simulated_distance)]);
                    disp(['Distance Mag: Linear state: ' num2str(data_int16_Actualdistance)]);
                    disp(['CPA_magnetic_field: ' num2str(CPA_magnetic_field)]);
                    disp('Reserved:');
                    disp(data_Reserved');
                    disp('Input Value:');
                    disp(data_value');
                    app.SimulatedtypeDropDown.Value = 'Magnetic Variation';
                    app.SimulatedtypeDropDown.Editable = false;
                    app.DurationsEditField.Value = data_duration;
                    app.PointsEditField.Value = data_uint32_numberofpoint;
                    app.VelocityEditField.Value = data_int16_velocity;
                    app.SimulateddistancemEditField.Value = data_int16_simulated_distance;
                    app.LinearVelocitymmsEditField.Value = data_uint16_linearvelocity;
                    app.DistanceMagneticmmEditField.Value = data_int16_Actualdistance;
                    app.CPAmagneticfieldTEditField.Value = CPA_magnetic_field;
                    app.data_value = data_value;
                    x = double(m * double(1:round(data_duration./m)));
                    plot(app.UIAxes, x, data_value*1000000);
                    disableDefaultInteractivity(app.UIAxes);
                    zoom(app.UIAxes, 'off');
                    if CPA_magnetic_field >0
                        y_max = CPA_magnetic_field*1000000;
                        y_min = - CPA_magnetic_field*1000000;
                    else
                        y_max = - CPA_magnetic_field*1000000;
                        y_min = CPA_magnetic_field*1000000;
                    end
                    if min(data_value) ~= max(data_value)
                        ylim(app.UIAxes, [y_min, y_max]);
                    else
                        y_max = 2*y_max;
                        y_min =2*y_min;
                        ylim(app.UIAxes, [y_min, y_max]);
                    end
                    xlim(app.UIAxes, [min(x),max(x)]);

                elseif data_type == 2
                    data_value_convert = data_value_convert(end-(data_duration./m)+1:end);
                    data_uint16_linearvelocity = double(data_uint16_linearvelocity) * 1000 / 65535;
                    data_uint16_linearvelocity = round(data_uint16_linearvelocity,1);
                    data_value = data_value_convert ./ 32767;
                    max_data_value = max(data_value);
                    data_value = double(double(data_value/max_data_value)*CPA_magnetic_field);
                    data_int16_velocity = double(double(double(data_int16_velocity_knot) * double(15.433333))/32767);
                    data_int16_velocity = round(data_int16_velocity,2);
                    % Display data
                    disp(['Char: ' data_char]);
                    disp(['Control: ' num2str(data_type) ' - Move motion']);
                    disp(['Duration: ' num2str(data_duration) 's']);
                    disp(['Number of Sample: ' num2str(data_uint32_numberofpoint)]);
                    disp(['Velocity: ' num2str(data_int16_velocity)]);
                    disp(['Linear Velocity: ' num2str(data_uint16_linearvelocity)]);
                    disp(['Distance to DUT: ' num2str(data_int16_simulated_distance)]);
                    disp(['Distance Mag: Linear state: ' num2str(data_int16_Actualdistance)]);
                    disp(['CPA_magnetic_field: ' num2str(CPA_magnetic_field)]);
                    disp('Reserved:');
                    disp(data_Reserved');
                    disp('Input Value:');
                    disp(data_value');
                    app.SimulatedtypeDropDown.Value = 'Magnetic Movement';
                    app.SimulatedtypeDropDown.Editable = false;
                    app.SimulatedtypeDropDown.Editable = false;
                    app.DurationsEditField.Value = data_duration;
                    app.PointsEditField.Value = data_uint32_numberofpoint;
                    app.VelocityEditField.Value = data_int16_velocity;
                    app.SimulateddistancemEditField.Value = data_int16_simulated_distance;
                    app.LinearVelocitymmsEditField.Enable = 'on';
                    app.LinearVelocitymmsEditField.Value = data_uint16_linearvelocity;
                    app.DistanceMagneticmmEditField.Value = data_int16_Actualdistance;
                    app.CPAmagneticfieldTEditField.Value = CPA_magnetic_field;
                    app.data_value = data_value;
                    x = double(m * double(1:round(data_duration./m)));
                    plot(app.UIAxes, x, data_value*1000000);
                    disableDefaultInteractivity(app.UIAxes);
                    zoom(app.UIAxes, 'off');
                    if CPA_magnetic_field >0
                        y_max = CPA_magnetic_field*1000000 ;
                        y_min = - CPA_magnetic_field*1000000;
                    else
                        y_max = - CPA_magnetic_field*1000000;
                        y_min = CPA_magnetic_field*1000000;
                    end
                    y_max = 2*y_max;
                    y_min =2*y_min; 
                    ylim(app.UIAxes, [y_min, y_max]);
                    xlim(app.UIAxes, [min(x),max(x)]);
                end
            
                % Display the number of bytes used
                current_position = ftell(fid);
                disp(['Number of bytes used: ' num2str(current_position)]);
                numberofbyte = data_uint32_numberofpoint*2 +68;
                disp(numberofbyte);
                if numberofbyte ~= current_position || current_position < 70
                    app.errorreadfile=errorreadfile(app,fileName);
                    return
                end
                % Close the file
                fclose(fid);
                app.GenerateWaveformButton.Enable = 'off';
                catch
                fclose(fid);
                %message = sprintf('Error reading file.');
                
                app.errorreadfile=errorreadfile(app,fileName);
                app.SaveMenu.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.SavecsvMenu.Enable = 'off';
                %error('Error reading file.');
            
            
                end
                app.KnotMenu.Enable = 'off';
                app.msMenu.Enable = 'off';
                  if data_int16_velocity == 0;
                    app.SavecsvMenu.Enable = 'off';
                    app.SaveMenu.Enable = 'off';
                    app.SaveButton.Enable = 'off';
                else
                app.SaveMenu.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.SavecsvMenu.Enable = 'on';
                app.data_type = data_type;
                app.data_duration = data_duration;
                app.data_uint32_numberofpoint = data_uint32_numberofpoint;
                app.data_int16_velocity = data_int16_velocity;
                app.data_uint16_linearvelocity = data_uint16_linearvelocity;
                app.data_int16_simulated_distance = data_int16_simulated_distance;
                app.data_int16_Actualdistance = data_int16_Actualdistance;
                app.CPA_magnetic_field = CPA_magnetic_field;
                app.data_value = data_value;
                app.b = data_value;
              end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            data_type = app.data_type;
            data_duration = app.data_duration;
            data_uint32_numberofpoint = app.data_uint32_numberofpoint;
            data_int16_velocity = app.data_int16_velocity;
            data_uint16_linearvelocity = app.data_uint16_linearvelocity;
            data_int16_simulated_distance = app.data_int16_simulated_distance;
            data_int16_Actualdistance = app.data_int16_Actualdistance;
            CPA_magnetic_field = app.CPA_magnetic_field;
            data_value = app.data_value;
            data_value_write = app.data_value_write;
            %data_unit = app.data_unit
            
            
            % Step 1: Get user's Documents path
            userProfilePath = getenv('USERPROFILE');
            documentsPath = fullfile(userProfilePath, 'Documents');

            % Step 2: Define a new folder name
            newFolderName = 'Magnetic Signature';

            % Step 3: Create the full path for new folder
            newFolderPath = fullfile(documentsPath, newFolderName);

            % Step 4: Create new folder
            [status, msg, msgID] = mkdir(newFolderPath);

            % Check the result
            if status
                sprintf('Directory "%s" has been created successfully.\n', newFolderPath);
            else
                sprintf('Unable to create directory. Error: %s\n', msg);
                return;
            end
            
            % Default folder
            defaultFolder = fullfile(getenv('USERPROFILE'), 'Documents\Magnetic Signature');
            app.defaultFolder=defaultFolder;
            app.savefilename=savefilename(app)
            
            
        end

        % Menu selected function: SavecsvMenu
        function SavecsvMenuSelected(app, event)
            data_type = app.data_type;
            data_duration = app.data_duration;
            data_uint32_numberofpoint = app.data_uint32_numberofpoint;
            data_int16_velocity_knot = app.data_int16_velocity_knot;
            data_uint16_linearvelocity = app.data_uint16_linearvelocity;
            data_int16_simulated_distance = app.data_int16_simulated_distance;
            data_int16_Actualdistance = app.data_int16_Actualdistance;
            CPA_magnetic_field = app.CPA_magnetic_field;
            data_value = app.data_value;
            data_value_write = app.data_value_write;
            b = app.b;
            % Step 1: Get user's Documents path
            userProfilePath = getenv('USERPROFILE');
            documentsPath = fullfile(userProfilePath, 'Documents');

            % Step 2: Define a new folder name
            newFolderName = 'Magnetic Signature';

            % Step 3: Create the full path for new folder
            newFolderPath = fullfile(documentsPath, newFolderName);

            % Step 4: Create new folder
            [status, msg, msgID] = mkdir(newFolderPath);

            % Check the result
            if status
                sprintf('Directory "%s" has been created successfully.\n', newFolderPath);
            else
                sprintf('Unable to create directory. Error: %s\n', msg);
                return;
            end
            
            % Default folder
            defaultFolder = fullfile(getenv('USERPROFILE'), 'Documents\Magnetic Signature');
            
            app.defaultFolder=defaultFolder;
            
            app.savefilecsvname=savefilecsvname(app)
            
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            m = app.m;
            app.KnotMenu.Enable = 'on';
            app.msMenu.Enable = 'on';
            status_import = 1;
            app.status_import = status_import;
            disp(status_import);
            app.DurationsEditField.Editable = true;
            app.DistanceMagneticmmEditField.Editable = true;
            app.CPAmagneticfieldTEditField.Editable = true;
            app.LinearVelocitymmsEditField.Editable = true;
            app.SimulateddistancemEditField.Editable = true;
            app.VelocityEditField.Editable = true;
            app.PointsEditField.Editable = true;
            status_open = 0;
            app.status_open = status_open;
            disp(status_open);
            
            [fileName, filePath] = uigetfile('*.csv', 'Select a CSV file');
        
            % Check if the user canceled the dialog
            if isequal(fileName, 0)
                disp('User canceled the file selection');
                status_import = 0;
                app.status_import = status_import;
                return; 
            end
            % Create the full path for the .csv file
            csvFilePath = fullfile(filePath, fileName);
            try
                % Read the CSV file without headers
                opts = detectImportOptions(csvFilePath, 'NumHeaderLines', 1);
                opts.SelectedVariableNames = opts.VariableNames{2}; % Select only the first column
                data = readtable(csvFilePath, opts);
                % Extract data from the first column
                data_value = data{:, 1}; % Extract data as a vector from the first column
        
                % Plot the data on UIAxes
                data_uint32_numberofpoint = 1:length(data_value);
                points = max (data_uint32_numberofpoint);
                min_value = min(data_value);
                max_value = max(data_value);
                % Kiểm tra điều kiện ban đầu
                if max_value == min_value
                    app.SimulatedtypeDropDown.Value = 'Magnetic Movement';
                    app.SimulatedtypeDropDown.Editable = false;
                    app.DurationsEditField.Enable = 'on';
                    app.DurationsEditField.Editable = false;
                    app.DistanceMagneticmmEditField.Enable = 'on';
                    app.PointsEditField.Enable = 'on';
                    app.PointsEditField.Editable = false;
                    app.SimulateddistancemEditField.Enable = 'on';
                    app.VelocityEditField.Enable = 'on';
                    app.GenerateWaveformButton.Enable = 'off';
                    app.CPAmagneticfieldTEditField.Enable = 'on';
                    app.CPAmagneticfieldTEditField.Editable = false;
                    app.LinearVelocitymmsEditField.Enable = 'off';
                    app.LinearVelocitymmsEditField.Value = 0;
                    data_type = 2;
                    app.data_type = data_type;

                    CPA_scale = max_value;
                    % Kiểm tra giá trị của CPA_scale
                    if abs(CPA_scale) > 0.1
                        data_value = (data_value/ abs(CPA_scale)) * 0.1;
                        CPA_scale = (CPA_scale / abs(CPA_scale)) * 0.1;
                    else
                        if CPA_scale > 0
                            CPA_scale = CPA_scale;
                        else
                            CPA_scale = CPA_scale;
                        end
                    end
                else
                    app.SimulatedtypeDropDown.Value = 'Magnetic Variation';
                    app.SimulatedtypeDropDown.Editable = false;
                    app.DurationsEditField.Enable = 'on';
                    app.DurationsEditField.Editable = false;
                    app.DistanceMagneticmmEditField.Enable = 'on';
                    app.PointsEditField.Enable = 'on';
                    app.PointsEditField.Editable = false;
                    app.SimulateddistancemEditField.Enable = 'on';
                    app.VelocityEditField.Enable = 'on';
                    app.GenerateWaveformButton.Enable = 'off';
                    app.CPAmagneticfieldTEditField.Enable = 'on';
                    app.CPAmagneticfieldTEditField.Editable = false;
                    app.LinearVelocitymmsEditField.Enable = 'on';
                    app.LinearVelocitymmsEditField.Value = 0;
                    data_type = 1;
                    app.data_type = data_type;
                    if abs(max_value) < abs(min_value)
                        CPA_scale = min_value;
                        disp(CPA_scale);
                        % Kiểm tra giá trị của CPA_scale
                        if abs(CPA_scale) > 0.1
                            data_value = (data_value/ abs(CPA_scale)) * 0.1;
                            CPA_scale = (CPA_scale / abs(CPA_scale)) * 0.1;
                        else
                            if CPA_scale > 0
                                CPA_scale = CPA_scale;
                            else
                                CPA_scale = CPA_scale;
                            end
                        end
                    elseif abs(max_value) > abs(min_value)
                        CPA_scale = max_value;
                        disp(CPA_scale);
                        % Kiểm tra giá trị của CPA_scale
                        if abs(CPA_scale) > 0.1
                            data_value = (data_value/ abs(CPA_scale)) * 0.1;
                            CPA_scale = (CPA_scale / abs(CPA_scale)) * 0.1;
                        else
                            if CPA_scale > 0
                                CPA_scale = CPA_scale;
                            else
                                CPA_scale = CPA_scale;
                            end
                        end
                    elseif abs(max_value) == abs(min_value)
                        CPA_scale = max_value;
                        disp(CPA_scale);
                        % Kiểm tra giá trị của CPA_scale
                        if abs(CPA_scale) > 0.1
                            data_value = (data_value/ abs(CPA_scale)) * 0.1;
                            CPA_scale = (CPA_scale / abs(CPA_scale)) * 0.1;
                        else
                            if CPA_scale > 0
                                CPA_scale = CPA_scale;
                            else
                                CPA_scale = CPA_scale;
                            end
                        end
                    end
                end
                
                % Gán giá trị cuối cùng
                CPA = CPA_scale;
                %data_value = data_scale; % Giả sử bạn có giá trị data_scale cần gán
                
                % Hiển thị kết quả
                disp(['CPA: ', num2str(CPA)]);
                %disp(['data_value: ', num2str(data_value)]);
                
                disp(data_value);
                %plot(app.UIAxes,1:points,data_value);
               % Gán giá trị cuối cùng
                CPA_magnetic_field = CPA_scale;
                %data_value = data_scale; % Giả sử bạn có giá trị data_scale cần gán
                
                % Hiển thị kết quả
                disp(['CPA: ', num2str(CPA)]);
                plot(app.UIAxes,data_uint32_numberofpoint,data_value*1000000);
                disableDefaultInteractivity(app.UIAxes);
                if CPA_magnetic_field >0
                    y_max = CPA_magnetic_field*1000000;
                    y_min = - CPA_magnetic_field*1000000;
                else
                    y_max = - CPA_magnetic_field*1000000;
                    y_min = CPA_magnetic_field*1000000;
                end
                axis(app.UIAxes, 'tight');
                zoom(app.UIAxes, 'off');
                if data_type == 1
                    ylim(app.UIAxes, [y_min, y_max]);
                    %app.UIAxes.YTick = [y_min 0 y_max];
                else
                    y_max = 2*y_max;
                    y_min =2*y_min;
                    ylim(app.UIAxes, [y_min, y_max]);
                end
                xlabel(app.UIAxes, 'Points');
                app.DurationsEditField.Value = points*m;
                app.PointsEditField.Value = points;
                app.CPAmagneticfieldTEditField.Value = CPA_magnetic_field;
                app.CPA_magnetic_field = CPA_magnetic_field;
                app.data_uint32_numberofpoint = points;
                app.data_value = data_value;
                disp(data_value);
                %app.DurationsEditField.Value = 0;
                app.VelocityEditField.Value = 0;
                app.SimulateddistancemEditField.Value = 1;
                app.DistanceMagneticmmEditField.Value = 1;
            catch ME
                %Display error message if something goes wrong
                %msgbox(sprintf('Error reading the CSV file: %s', ME.message), 'Error', 'error');
                %message = sprintf('Error reading the CSV file: %s', fileName);
                app.errorreadfile=errorreadfile(app,fileName);
                app.SaveMenu.Enable = 'off';
                app.SaveButton.Enable = 'off';
                app.SavecsvMenu.Enable = 'off';
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            about
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            % Get the folder path of the current script or function
            currentFilePath = mfilename('fullpath');
            currentFolder = fileparts(currentFilePath);
            htmlFilePath = fullfile(currentFolder, 'Manual_MagneticFieldSignal.html'); 
            % Construct the system command to open the file with the default web browser
            if ispc
                % Windows
                system(['start "" "' htmlFilePath '"']);
            elseif ismac
                % macOS
                system(['open "' htmlFilePath '"']);
            elseif isunix
                % Linux
                system(['xdg-open "' htmlFilePath '"']);
            else
                % Unsupported OS
                uialert(app.UIFigure, 'Unsupported operating system', 'Error');
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.KnotMenu.Enable = 'on';
            app.msMenu.Enable = 'on';
            app.DurationsEditField.Value = 0;
            app.DurationsEditField.Editable = true;
            app.DistanceMagneticmmEditField.Value = 1;
            app.DistanceMagneticmmEditField.Editable = true;
            app.CPAmagneticfieldTEditField.Value = 0;
            app.CPAmagneticfieldTEditField.Editable = true;
            app.LinearVelocitymmsEditField.Value = 0;
            app.LinearVelocitymmsEditField.Editable = true;
            app.SimulateddistancemEditField.Value = 1;
            app.SimulateddistancemEditField.Editable = true;
            app.VelocityEditField.Value = 0;
            app.VelocityEditField.Editable = true;
            app.PointsEditField.Value = 1;
            app.PointsEditField.Editable = true;
            app.SaveMenu.Enable = 'off';
            app.SaveButton.Enable = 'off';
            app.SavecsvMenu.Enable = 'off';
            app.GenerateWaveformButton.Enable = 'off';
            delete(app.UIAxes.Children);
            value = app.SimulatedtypeDropDown.Value;
            disp(value);
            switch value
                case 'Magnetic Variation'
                    
                case 'Magnetic Movement'
                    app.LinearVelocitymmsEditField.Enable='off';
            end
            status_import = 0;
            app.status_import = status_import;
            status_open = 0;
            app.status_open = status_open;
        end

        % Menu selected function: VelocityunitMenu
        function VelocityunitMenuSelected(app, event)
            
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            data_type = app.data_type;
            data_duration = app.data_duration;
            data_uint32_numberofpoint = app.data_uint32_numberofpoint;
            data_int16_velocity = app.data_int16_velocity;
            data_uint16_linearvelocity = app.data_uint16_linearvelocity;
            data_int16_simulated_distance = app.data_int16_simulated_distance;
            data_int16_Actualdistance = app.data_int16_Actualdistance;
            CPA_magnetic_field = app.CPA_magnetic_field;
            data_value = app.data_value;
            data_value_write = app.data_value_write;
            
            % Step 1: Get user's Documents path
            userProfilePath = getenv('USERPROFILE');
            documentsPath = fullfile(userProfilePath, 'Documents');

            % Step 2: Define a new folder name
            newFolderName = 'Magnetic Signature';

            % Step 3: Create the full path for new folder
            newFolderPath = fullfile(documentsPath, newFolderName);

            % Step 4: Create new folder
            [status, msg, msgID] = mkdir(newFolderPath);

            % Check the result
            if status
                sprintf('Directory "%s" has been created successfully.\n', newFolderPath);
            else
                sprintf('Unable to create directory. Error: %s\n', msg);
                return;
            end
            % Default folder
            defaultFolder = fullfile(getenv('USERPROFILE'), 'Documents\Magnetic Signature');
            app.defaultFolder=defaultFolder;
            app.savefilename=savefilename(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneratesMagneticFieldSignalsUIFigure = uifigure('Visible', 'off');
            app.GeneratesMagneticFieldSignalsUIFigure.Name = 'Generates Magnetic Field Signals';
            app.GeneratesMagneticFieldSignalsUIFigure.Icon = 'magnet.png';
            app.FileMenu = uimenu(app.GeneratesMagneticFieldSignalsUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveMenu.Tag = 'SS3 Magnetic Signature';
            app.SavecsvMenu = uimenu(app.FileMenu);
            app.SavecsvMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecsvMenuSelected, true);
            app.SavecsvMenu.Text = 'Save "csv"';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ConfigurationMenu = uimenu(app.GeneratesMagneticFieldSignalsUIFigure);
            app.ConfigurationMenu.Text = 'Configuration';
            app.VelocityunitMenu = uimenu(app.ConfigurationMenu);
            app.VelocityunitMenu.MenuSelectedFcn = createCallbackFcn(app, @VelocityunitMenuSelected, true);
            app.VelocityunitMenu.Text = 'Velocity unit';
            app.KnotMenu = uimenu(app.VelocityunitMenu);
            app.KnotMenu.MenuSelectedFcn = createCallbackFcn(app, @KnotMenuSelected, true);
            app.KnotMenu.Text = 'Knot';
            app.msMenu = uimenu(app.VelocityunitMenu);
            app.msMenu.MenuSelectedFcn = createCallbackFcn(app, @msMenuSelected, true);
            app.msMenu.Text = 'm/s';
            app.NumberofpointMenu = uimenu(app.ConfigurationMenu);
            app.NumberofpointMenu.Text = 'Number of point';
            app.HelpMenu = uimenu(app.GeneratesMagneticFieldSignalsUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Text = 'Manual';
            app.Panel_4 = uipanel(app.GeneratesMagneticFieldSignalsUIFigure);
            app.SimulatedtypeDropDownLabel = uilabel(app.Panel_4);
            app.SimulatedtypeDropDownLabel.Text = 'Simulated type';
            app.SimulatedtypeDropDown = uidropdown(app.Panel_4);
            app.SimulatedtypeDropDown.Items = {'...', 'Magnetic Variation', 'Magnetic Movement'};
            app.SimulatedtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulatedtypeDropDownValueChanged, true);
            app.SimulatedtypeDropDown.Value = '...';
            app.DurationsEditField_2Label = uilabel(app.Panel_4);
            app.DurationsEditField_2Label.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.Panel_4, 'numeric');
            app.DurationsEditField.Limits = [0 600];
            app.DurationsEditField.ValueChangedFcn = createCallbackFcn(app, @DurationsEditFieldValueChanged, true);
            app.PointsEditFieldLabel = uilabel(app.Panel_4);
            app.PointsEditFieldLabel.Text = 'Points';
            app.PointsEditField = uieditfield(app.Panel_4, 'numeric');
            app.PointsEditField.Limits = [1 10000000];
            app.PointsEditField.ValueChangedFcn = createCallbackFcn(app, @PointsEditFieldValueChanged, true);
            app.PointsEditField.Value = 1;
            app.VelocityEditFieldLabel = uilabel(app.Panel_4);
            app.VelocityEditFieldLabel.Text = 'Velocity ';
            app.VelocityEditField = uieditfield(app.Panel_4, 'numeric');
            app.VelocityEditField.Limits = [0 30];
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.Label = uilabel(app.Panel_4);
            app.Label.Text = '';
            app.SimulateddistancemEditField_2Label = uilabel(app.Panel_4);
            app.SimulateddistancemEditField_2Label.Text = 'Simulated distance (m)';
            app.SimulateddistancemEditField = uieditfield(app.Panel_4, 'numeric');
            app.SimulateddistancemEditField.Limits = [1 100];
            app.SimulateddistancemEditField.ValueChangedFcn = createCallbackFcn(app, @SimulateddistancemEditFieldValueChanged, true);
            app.SimulateddistancemEditField.Value = 1;
            app.LinearVelocitymmsEditFieldLabel = uilabel(app.Panel_4);
            app.LinearVelocitymmsEditFieldLabel.Text = 'Linear Velocity (mm/s)';
            app.LinearVelocitymmsEditField = uieditfield(app.Panel_4, 'numeric');
            app.LinearVelocitymmsEditField.Limits = [0 1000];
            app.LinearVelocitymmsEditField.ValueChangedFcn = createCallbackFcn(app, @LinearVelocitymmsEditFieldValueChanged, true);
            app.CPAmagneticfieldTEditFieldLabel = uilabel(app.Panel_4);
            app.CPAmagneticfieldTEditFieldLabel.Text = 'CPA magnetic field (T)';
            app.CPAmagneticfieldTEditField = uieditfield(app.Panel_4, 'numeric');
            app.CPAmagneticfieldTEditField.Limits = [0 0.1];
            app.CPAmagneticfieldTEditField.ValueChangedFcn = createCallbackFcn(app, @CPAmagneticfieldTEditFieldValueChanged, true);
            app.DistanceMagneticmmEditFieldLabel = uilabel(app.Panel_4);
            app.DistanceMagneticmmEditFieldLabel.Text = 'Distance Magnetic (mm)';
            app.DistanceMagneticmmEditField = uieditfield(app.Panel_4, 'numeric');
            app.DistanceMagneticmmEditField.Limits = [1 300];
            app.DistanceMagneticmmEditField.ValueChangedFcn = createCallbackFcn(app, @DistanceMagneticmmEditFieldValueChanged, true);
            app.DistanceMagneticmmEditField.Value = 1;
            app.Panel_2 = uipanel(app.GeneratesMagneticFieldSignalsUIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Field strength (µT)')
            app.GenerateWaveformButton = uibutton(app.Panel_2, 'push');
            app.GenerateWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformButtonPushed, true);
            app.GenerateWaveformButton.Text = 'Generate Waveform';
            app.QuitButton = uibutton(app.Panel_2, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.SaveButton = uibutton(app.Panel_2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DefaultButton = uibutton(app.Panel_2, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.Panel_3 = uipanel(app.GeneratesMagneticFieldSignalsUIFigure);
            app.Image = uiimage(app.Panel_3);
            app.Image.ImageSource = 'bartington.png';
            app.AboutPanel = uipanel(app.GeneratesMagneticFieldSignalsUIFigure);
            app.AboutPanel.Title = 'About';
            app.CopyrightBartingtonInstrumentsLabel = uilabel(app.AboutPanel);
            app.CopyrightBartingtonInstrumentsLabel.Text = 'Copyright © Bartington Instruments';
            app.MAGNETICPATTERNPanel = uipanel(app.GeneratesMagneticFieldSignalsUIFigure);
            app.MAGNETICPATTERNPanel.Title = 'MAGNETIC PATTERN';
            app.Panel_6 = uipanel(app.GeneratesMagneticFieldSignalsUIFigure);
            app.Version1223Label = uilabel(app.Panel_6);
            app.Version1223Label.Text = 'Version 1.2.23';
            % Show the figure after all components are created
            app.GeneratesMagneticFieldSignalsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SS3_Magnetic_Signature_app_2606_csv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneratesMagneticFieldSignalsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneratesMagneticFieldSignalsUIFigure)
        end
    end
end